classdef CTtrimmerVer30 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Panel_4         matlab.ui.container.Panel
        SaveEditField   matlab.ui.control.EditField
        saveBtn         matlab.ui.control.Button
        EditField2      matlab.ui.control.EditField
        dicomTextField  matlab.ui.control.EditField
        WhichToSaveBtn  matlab.ui.control.Button
        dicomBtn        matlab.ui.control.Button
        Panel_3         matlab.ui.container.Panel
        Z1sld           matlab.ui.control.Slider
        axYZ            matlab.ui.control.Image
        Panel_2         matlab.ui.container.Panel
        Z2sld           matlab.ui.control.Slider
        axZX            matlab.ui.control.Image
        Panel           matlab.ui.container.Panel
        X2sld           matlab.ui.control.Slider
        X1sld           matlab.ui.control.Slider
        Y1sld           matlab.ui.control.Slider
        Y2sld           matlab.ui.control.Slider
        axXY            matlab.ui.control.Image
    end

    
    properties (Access = private)
        path = 'dicomデータセットの格納されているディレクトリを選択';
        VV = 0;
        X1 = 1;
        X2 = 512;
        Y1 = 1;
        Y2 = 512;
        Z1 = 1;
        Z2 = 512;
        XYimage = 0;
        YZimage = 0;
        ZXimage = 0;
        dicominfo
        savepath = 'トリミング済みCTデータの保存先を選択';
        savename = '保存名';
    end
    
    methods (Access = private)
        
    function impreview(app)
                
        previewXY=insertShape(app.XYimage,'Rectangle',...
            [app.X1 app.Y1 app.X2-app.X1 app.Y2-app.Y1],...
            'Color','blue','LineWidth',1);
        app.axXY.ImageSource=previewXY;
        
        previewYZ=insertShape(app.YZimage,'Rectangle',...
            [app.Z1 app.Y1 app.Z2-app.Z1 app.Y2-app.Y1],...
            'Color','red','LineWidth',1);
        app.axYZ.ImageSource=previewYZ;
        
        previewZX=insertShape(app.ZXimage,'Rectangle',...
            [app.Z1 app.X1 app.Z2-app.Z1 app.X2-app.X1],...
            'Color','green','LineWidth',1);
        app.axZX.ImageSource=previewZX;
        
    end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: dicomBtn
        function dicomBtnButtonPushed(app, event)
        dirname=uigetdir;
        app.path=dirname;
        app.dicomTextField.Value=string(app.path);
        
        %読み取りファイル定義
        list=dir(dirname);
        listcell=struct2cell(list);
        filenamelist=listcell(1,:);
        filenamelist2=append(dirname,'/',filenamelist); %Macでは/、Windowsでは\
        filenamelist3=rot90(filenamelist2,3);
        listsize=size(filenamelist,2);
        
        app.Z1sld.Limits=[0 listsize-2];
        app.Z2sld.Limits=[0 listsize-2];
        app.Z2sld.Value=listsize-2;
        app.Z2=listsize-2;
        app.X1=1;
        app.X2=512;
        app.Y1=1;
        app.Y2=512;
        app.Z1=1;
        app.X1sld.Value=0;
        app.X2sld.Value=app.X2;
        app.Y1sld.Value=0;
        app.Y2sld.Value=-app.Y2;
        app.Z1sld.Value=-0;
        app.Z2sld.Value=app.Z2;
        
        [a,app.dicominfo]=dicomreadVolume(filenamelist3(3:4));
        V=dicomread(cell2mat(filenamelist3(3)));
        
        for n=4:listsize
            V=cat(3,V,dicomread(cell2mat(filenamelist3(n))));           
        end
        
        app.VV=V;
        
        app.XYimage=(squeeze(mean(V,3)+1024))/2048;
        app.YZimage=(squeeze(mean(V,2)+1024))/1024;
        app.ZXimage=(squeeze(mean(V,1)+1024))/1024;
        
        impreview(app);
        
        v1=V>-100 & V<150;
        volshow(v1);
        end

        % Button pushed function: WhichToSaveBtn
        function WhichToSaveBtnButtonPushed(app, event)
        dirname=uigetdir;
        app.savepath=dirname;
        app.EditField2.Value=string(app.savepath);
        cd(app.savepath)
        end

        % Value changed function: SaveEditField
        function SaveEditFieldValueChanged(app, event)
            value = app.SaveEditField.Value;
            app.savename=string(value);
        end

        % Button pushed function: saveBtn
        function saveBtnButtonPushed(app, event)
           v=app.VV(app.Y1:app.Y2,app.X1:app.X2,app.Z1:app.Z2);
       
           volshow(imbinarize(v,0.5));
           dic = app.dicominfo;
           save(app.savename,'v','dic');
           
           app.dicominfo.PixelSpacings
           s_v = size(v);
           s_v.*dic.PixelSpacings(1);
       
        end

        % Value changed function: X1sld
        function X1sldValueChanged(app, event)
            value = app.X1sld.Value;
            app.X1=round(value);
            impreview(app);
        end

        % Value changed function: X2sld
        function X2sldValueChanged(app, event)
            value = app.X2sld.Value;
            app.X2=round(value);
            impreview(app);
        end

        % Value changed function: Y1sld
        function Y1sldValueChanged(app, event)
            value = app.Y1sld.Value;
            app.Y1=-round(value);
            impreview(app);
        end

        % Value changed function: Y2sld
        function Y2sldValueChanged(app, event)
            value = app.Y2sld.Value;
            app.Y2=round(-value);
            impreview(app);
        end

        % Value changed function: Z1sld
        function Z1sldValueChanged(app, event)
            value = app.Z1sld.Value;
            app.Z1=round(value);
            impreview(app);
        end

        % Value changed function: Z2sld
        function Z2sldValueChanged(app, event)
            value = app.Z2sld.Value;
            app.Z2=round(value);
            impreview(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.axXY = uiimage(app.Panel);
            app.Y2sld = uislider(app.Panel);
            app.Y2sld.Limits = [-512 0];
            app.Y2sld.ValueChangedFcn = createCallbackFcn(app, @Y2sldValueChanged, true);
            app.Y1sld = uislider(app.Panel);
            app.Y1sld.Limits = [-512 0];
            app.Y1sld.ValueChangedFcn = createCallbackFcn(app, @Y1sldValueChanged, true);
            app.X1sld = uislider(app.Panel);
            app.X1sld.Limits = [0 512];
            app.X1sld.ValueChangedFcn = createCallbackFcn(app, @X1sldValueChanged, true);
            app.X2sld = uislider(app.Panel);
            app.X2sld.Limits = [0 512];
            app.X2sld.ValueChangedFcn = createCallbackFcn(app, @X2sldValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.axZX = uiimage(app.Panel_2);
            app.Z2sld = uislider(app.Panel_2);
            app.Z2sld.Limits = [0 512];
            app.Z2sld.ValueChangedFcn = createCallbackFcn(app, @Z2sldValueChanged, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.axYZ = uiimage(app.Panel_3);
            app.Z1sld = uislider(app.Panel_3);
            app.Z1sld.Limits = [0 512];
            app.Z1sld.ValueChangedFcn = createCallbackFcn(app, @Z1sldValueChanged, true);
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Title = 'データ';
            app.dicomBtn = uibutton(app.Panel_4, 'push');
            app.dicomBtn.ButtonPushedFcn = createCallbackFcn(app, @dicomBtnButtonPushed, true);
            app.dicomBtn.Text = 'DICOM';
            app.WhichToSaveBtn = uibutton(app.Panel_4, 'push');
            app.WhichToSaveBtn.ButtonPushedFcn = createCallbackFcn(app, @WhichToSaveBtnButtonPushed, true);
            app.WhichToSaveBtn.Text = '保存先';
            app.dicomTextField = uieditfield(app.Panel_4, 'text');
            app.EditField2 = uieditfield(app.Panel_4, 'text');
            app.saveBtn = uibutton(app.Panel_4, 'push');
            app.saveBtn.ButtonPushedFcn = createCallbackFcn(app, @saveBtnButtonPushed, true);
            app.saveBtn.Text = '保存';
            app.SaveEditField = uieditfield(app.Panel_4, 'text');
            app.SaveEditField.ValueChangedFcn = createCallbackFcn(app, @SaveEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CTtrimmerVer30

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end