classdef HorofVer5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ControlPanel2      matlab.ui.container.Panel
        ControlRotPanel_2  matlab.ui.container.Panel
        plus10_r_2         matlab.ui.control.Button
        plus5_r_2          matlab.ui.control.Button
        plus1_r_2          matlab.ui.control.Button
        minu1_r_2          matlab.ui.control.Button
        minu5_r_2          matlab.ui.control.Button
        minu10_r_2         matlab.ui.control.Button
        ControlPosPanel_2  matlab.ui.container.Panel
        plus10_2           matlab.ui.control.Button
        plus5_2            matlab.ui.control.Button
        plus1_2            matlab.ui.control.Button
        minu1_2            matlab.ui.control.Button
        minu5_2            matlab.ui.control.Button
        minu10_2           matlab.ui.control.Button
        ControlPanel3      matlab.ui.container.Panel
        ControlRotPanel_3  matlab.ui.container.Panel
        plus10_r_3         matlab.ui.control.Button
        plus5_r_3          matlab.ui.control.Button
        plus1_r_3          matlab.ui.control.Button
        minu1_r_3          matlab.ui.control.Button
        minu5_r_3          matlab.ui.control.Button
        minu10_r_3         matlab.ui.control.Button
        ControlPosPanel_3  matlab.ui.container.Panel
        plus10_3           matlab.ui.control.Button
        plus5_3            matlab.ui.control.Button
        plus1_3            matlab.ui.control.Button
        minu1_3            matlab.ui.control.Button
        minu5_3            matlab.ui.control.Button
        minu10_3           matlab.ui.control.Button
        ControlPanel       matlab.ui.container.Panel
        ControlRotPanel    matlab.ui.container.Panel
        plus10_r           matlab.ui.control.Button
        plus5_r            matlab.ui.control.Button
        plus1_r            matlab.ui.control.Button
        minu1_r            matlab.ui.control.Button
        minu5_r            matlab.ui.control.Button
        minu10_r           matlab.ui.control.Button
        ControlPosPanel    matlab.ui.container.Panel
        plus10             matlab.ui.control.Button
        plus5              matlab.ui.control.Button
        plus1              matlab.ui.control.Button
        minu1              matlab.ui.control.Button
        minu5              matlab.ui.control.Button
        minu10             matlab.ui.control.Button
        Panel_7            matlab.ui.container.Panel
        XZEditField        matlab.ui.control.NumericEditField
        XZEditFieldLabel   matlab.ui.control.Label
        YZEditField        matlab.ui.control.NumericEditField
        YZEditFieldLabel   matlab.ui.control.Label
        XYEditField        matlab.ui.control.NumericEditField
        XYEditFieldLabel   matlab.ui.control.Label
        Button_40          matlab.ui.control.Button
        Button_41          matlab.ui.control.Button
        Button2_4          matlab.ui.control.Button
        Button2_3          matlab.ui.control.Button
        Button2_2          matlab.ui.control.Button
        Button2            matlab.ui.control.Button
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_3           matlab.ui.control.RadioButton
        Button_2           matlab.ui.control.RadioButton
        Button             matlab.ui.control.Button
        Image_3            matlab.ui.control.Image
        Image_2            matlab.ui.control.Image
        Image              matlab.ui.control.Image
    end

    
    properties (Access = private)
        %ベクトル、座標、倍率初期値
        CurrentX=[1 0 0]; %Xベクトル
        CurrentY=[0 1 0]; %Yベクトル
        CurrentZ=[0 0 1]; %Zベクトル
        CurrentO=[0 0 0]; %ベクトル原点座標
        %変数初期値
        V=0; %ボリューム
        file=0;
        VR=0; %ボリュームのR成分
        VG=0; %ボリュームのG成分
        VB=0; %ボリュームのB成分
        Label=0; %air=0,tissue=1,trmd=2のラベル
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_3 = uiimage(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '右足';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '左足';
            app.Panel_7 = uipanel(app.UIFigure);
            app.Button2 = uibutton(app.Panel_7, 'push');
            app.Button2.Text = 'Button2';
            app.Button2_2 = uibutton(app.Panel_7, 'push');
            app.Button2_2.Text = 'Button2';
            app.Button2_3 = uibutton(app.Panel_7, 'push');
            app.Button2_3.Text = 'Button2';
            app.Button2_4 = uibutton(app.Panel_7, 'push');
            app.Button2_4.Text = 'Button2';
            app.Button_41 = uibutton(app.Panel_7, 'push');
            app.Button_41.Text = '保存';
            app.Button_40 = uibutton(app.Panel_7, 'push');
            app.Button_40.Text = '最初から';
            app.XYEditFieldLabel = uilabel(app.Panel_7);
            app.XYEditFieldLabel.Text = 'XY平面';
            app.XYEditField = uieditfield(app.Panel_7, 'numeric');
            app.YZEditFieldLabel = uilabel(app.Panel_7);
            app.YZEditFieldLabel.Text = 'YZ平面';
            app.YZEditField = uieditfield(app.Panel_7, 'numeric');
            app.XZEditFieldLabel = uilabel(app.Panel_7);
            app.XZEditFieldLabel.Text = 'XZ平面';
            app.XZEditField = uieditfield(app.Panel_7, 'numeric');
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPosPanel = uipanel(app.ControlPanel);
            app.minu10 = uibutton(app.ControlPosPanel, 'push');
            app.minu10.Text = '+10';
            app.minu5 = uibutton(app.ControlPosPanel, 'push');
            app.minu5.Text = '+10';
            app.minu1 = uibutton(app.ControlPosPanel, 'push');
            app.minu1.Text = '+10';
            app.plus1 = uibutton(app.ControlPosPanel, 'push');
            app.plus1.Text = '+10';
            app.plus5 = uibutton(app.ControlPosPanel, 'push');
            app.plus5.Text = '+10';
            app.plus10 = uibutton(app.ControlPosPanel, 'push');
            app.plus10.Text = '+10';
            app.ControlRotPanel = uipanel(app.ControlPanel);
            app.minu10_r = uibutton(app.ControlRotPanel, 'push');
            app.minu10_r.Text = '+10';
            app.minu5_r = uibutton(app.ControlRotPanel, 'push');
            app.minu5_r.Text = '+10';
            app.minu1_r = uibutton(app.ControlRotPanel, 'push');
            app.minu1_r.Text = '+10';
            app.plus1_r = uibutton(app.ControlRotPanel, 'push');
            app.plus1_r.Text = '+10';
            app.plus5_r = uibutton(app.ControlRotPanel, 'push');
            app.plus5_r.Text = '+10';
            app.plus10_r = uibutton(app.ControlRotPanel, 'push');
            app.plus10_r.Text = '+10';
            app.ControlPanel3 = uipanel(app.UIFigure);
            app.ControlPosPanel_3 = uipanel(app.ControlPanel3);
            app.minu10_3 = uibutton(app.ControlPosPanel_3, 'push');
            app.minu10_3.Text = '+10';
            app.minu5_3 = uibutton(app.ControlPosPanel_3, 'push');
            app.minu5_3.Text = '+10';
            app.minu1_3 = uibutton(app.ControlPosPanel_3, 'push');
            app.minu1_3.Text = '+10';
            app.plus1_3 = uibutton(app.ControlPosPanel_3, 'push');
            app.plus1_3.Text = '+10';
            app.plus5_3 = uibutton(app.ControlPosPanel_3, 'push');
            app.plus5_3.Text = '+10';
            app.plus10_3 = uibutton(app.ControlPosPanel_3, 'push');
            app.plus10_3.Text = '+10';
            app.ControlRotPanel_3 = uipanel(app.ControlPanel3);
            app.minu10_r_3 = uibutton(app.ControlRotPanel_3, 'push');
            app.minu10_r_3.Text = '+10';
            app.minu5_r_3 = uibutton(app.ControlRotPanel_3, 'push');
            app.minu5_r_3.Text = '+10';
            app.minu1_r_3 = uibutton(app.ControlRotPanel_3, 'push');
            app.minu1_r_3.Text = '+10';
            app.plus1_r_3 = uibutton(app.ControlRotPanel_3, 'push');
            app.plus1_r_3.Text = '+10';
            app.plus5_r_3 = uibutton(app.ControlRotPanel_3, 'push');
            app.plus5_r_3.Text = '+10';
            app.plus10_r_3 = uibutton(app.ControlRotPanel_3, 'push');
            app.plus10_r_3.Text = '+10';
            app.ControlPanel2 = uipanel(app.UIFigure);
            app.ControlPosPanel_2 = uipanel(app.ControlPanel2);
            app.minu10_2 = uibutton(app.ControlPosPanel_2, 'push');
            app.minu10_2.Text = '+10';
            app.minu5_2 = uibutton(app.ControlPosPanel_2, 'push');
            app.minu5_2.Text = '+10';
            app.minu1_2 = uibutton(app.ControlPosPanel_2, 'push');
            app.minu1_2.Text = '+10';
            app.plus1_2 = uibutton(app.ControlPosPanel_2, 'push');
            app.plus1_2.Text = '+10';
            app.plus5_2 = uibutton(app.ControlPosPanel_2, 'push');
            app.plus5_2.Text = '+10';
            app.plus10_2 = uibutton(app.ControlPosPanel_2, 'push');
            app.plus10_2.Text = '+10';
            app.ControlRotPanel_2 = uipanel(app.ControlPanel2);
            app.minu10_r_2 = uibutton(app.ControlRotPanel_2, 'push');
            app.minu10_r_2.Text = '+10';
            app.minu5_r_2 = uibutton(app.ControlRotPanel_2, 'push');
            app.minu5_r_2.Text = '+10';
            app.minu1_r_2 = uibutton(app.ControlRotPanel_2, 'push');
            app.minu1_r_2.Text = '+10';
            app.plus1_r_2 = uibutton(app.ControlRotPanel_2, 'push');
            app.plus1_r_2.Text = '+10';
            app.plus5_r_2 = uibutton(app.ControlRotPanel_2, 'push');
            app.plus5_r_2.Text = '+10';
            app.plus10_r_2 = uibutton(app.ControlRotPanel_2, 'push');
            app.plus10_r_2.Text = '+10';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HorofVer5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end