classdef makeTrainingData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        OnlyOneTab             matlab.ui.container.Tab
        ResetDataBtn           matlab.ui.control.Button
        ExportDataField        matlab.ui.control.EditField
        SelectedDataField      matlab.ui.control.EditField
        makeTrainDataBtn       matlab.ui.control.Button
        DecideDataBtn          matlab.ui.control.Button
        WhichLegGroup          matlab.ui.container.ButtonGroup
        LeftLeg                matlab.ui.control.RadioButton
        RightLeg               matlab.ui.control.RadioButton
        SelectDataBtn          matlab.ui.control.Button
        ForDirTab              matlab.ui.container.Tab
        sampleViewBtn          matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        makeTrainDataALLBtn    matlab.ui.control.Button
        ResetDataBtn_2         matlab.ui.control.Button
        DataLengthField        matlab.ui.control.NumericEditField
        Label_7                matlab.ui.control.Label
        WhichLegGroup_2        matlab.ui.container.ButtonGroup
        LeftLeg_2              matlab.ui.control.RadioButton
        RightLeg_2             matlab.ui.control.RadioButton
        SelectedDirField       matlab.ui.control.EditField
        DirSelectDataBtn       matlab.ui.control.Button
        Panel_2                matlab.ui.container.Panel
        mm2CTEditField_2       matlab.ui.control.NumericEditField
        mm2CTLabel             matlab.ui.control.Label
        mm3EditField_8         matlab.ui.control.NumericEditField
        mm3EditField_8Label    matlab.ui.control.Label
        mm3CTEditField_4       matlab.ui.control.NumericEditField
        mm3CTEditField_4Label  matlab.ui.control.Label
        mm3EditField_7         matlab.ui.control.NumericEditField
        mm3EditField_7Label    matlab.ui.control.Label
        mm3CTEditField_3       matlab.ui.control.NumericEditField
        mm3CTEditField_3Label  matlab.ui.control.Label
        mm3EditField_6         matlab.ui.control.NumericEditField
        mm3EditField_6Label    matlab.ui.control.Label
        threshold3Field        matlab.ui.control.NumericEditField
        Label_6                matlab.ui.control.Label
        threshold2Field        matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        Label_4                matlab.ui.control.Label
        threshold1Field        matlab.ui.control.NumericEditField
        OrthoSliceViewerPanel  matlab.ui.container.Panel
        volumeViewerPanel      matlab.ui.container.Panel
    end

    
    properties (Access = private)
        %変数初期値
        thA=-100;
        thB=140;
        thC=300;
        thD=500000;
        thE=500000;
        thF=40000;
        thG=500000;
        thH=100000;
        thI=10000;
        x=1;
        y=1;
        z=1;
        volume=zeros([2 2 2]);
        
        T=0;
        B=0;
        H=0;
        file='0';
        path='0';
        hVol;
        matFiles
        matFilePaths
    end
    
    methods (Access = private)
        
        function [T,B,H]=makeTBH(~,v,x,y,z,a,b,c,d,e,f,g,h,i)
            T=v>a;
            T=bwareaopen(T,round(d/(x*y*z)),6);
            Ta=T==0;
            Ta=bwareaopen(Ta,round(e/(x*y*z)),6);
            T=Ta==0;
    
            A=v<b;
            H=v;
            H(A)=0;
            B=H>c;
            H(B)=0;
            H=H>b;
    
            H=bwareaopen(H,round(f/(x*y*z)),6);
    
            Tb=ones(size(v));
            Tb(B)=0;
            Tb=bwareaopen(Tb,round(i*x*y),4);
            B=Tb==0;
            B=bwareaopen(B,round(h*x*y*z),6);
    
            SE=strel("sphere",round(3/x));
            Hd=imdilate(H,SE);
            A=T==0;
            Ad=imdilate(A,SE);
            Hu=double(T)+double(Hd)+double(Ad);
            Hv=Hu==3;
            H(Hv)=1;
    
            Tb=T;
            Tb(H)=0;
            Tb=bwareaopen(Tb,round(g/(x*y*z)),6);
            H=T;
            H(Tb)=0;
        end
        
        function im = makeTrainData(app, filename,sleg,t,b,h)
            %右足はそのまま保存
            if sleg=="右足"
                Vfn=insertBefore(filename,".mat","_R_vol");
                Vfn=strjoin([string(pwd) 'VolumeData' Vfn],'/');
                vol=uint8(t);
                % 1mm->2mm
    %             vol=vol(1:2:143,1:2:143,1:2:287);
                save(Vfn,'vol');
                Bfn=insertBefore(filename,".mat","_R_bone");
                Bfn=strjoin([string(pwd) 'BoneData' Bfn],'/');
                bone=uint8(double(t)+double(h)*2+double(b));
                bone4=bone==4;
                bone(bone4)=3;
    %             bone=bone(1:2:143,1:2:143,1:2:287);
                save(Bfn,'bone');
                im=cat(1,vol,bone);
   
            %左足は左右反転して保存
            else
                Vfn=insertBefore(filename, ".mat","_L(rev)_vol");
                Vfn=strjoin([string(pwd) 'VolumeData' Vfn],'/');
                vol=uint8(t);
    %             vol=vol(1:2:143,1:2:143,1:2:287);
    
                vol=flip(vol,2);
    
                save(Vfn,'vol');
                Bfn=insertBefore(filename,".mat","_L(rev)_bone");
                Bfn=strjoin([string(pwd) 'BoneData' Bfn],'/');
                bone=uint8(double(t)+double()*2+double(app.B));
                bone4=bone==4;
                bone(bone4)=3;
    %             bone=bone(1:2:143,1:2:143,1:2:287);
    
                bone=flip(bone,2);
    
                save(Bfn,'bone');
                im=cat(1,vol,bone);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataBtn
        function SelectDataBtnButtonPushed(app, event)
            [app.file,app.path]=uigetfile;
                % ユーザーがファイル選択をキャンセルした場合、fileは0になる
            if isequal(app.file, 0)
                return; % ユーザーがファイル選択をキャンセルした場合、ここで関数を終了
            end

            load([app.path app.file],'v','dicominfo');
            cd(app.path);
            X=dicominfo.PixelSpacings(1,1);
            Y=dicominfo.PixelSpacings(2,1);
            Z=dicominfo.PatientPositions(2,3)-dicominfo.PatientPositions(1,3);
    
            %z=0.5
    
            %1ピクセル=1mmになるように拡大縮小
            vsize=[fix(size(v,1)*X) fix(size(v,2)*Y) fix(size(v,3)*Z)];
            v=imresize3(v,vsize);
            X=1;
            Y=1;
            Z=1;
    
            %144*144*288mmに調整
            v=padarray(v,[144 144 288],-2048,'post');
            vsize=size(v);
            v=v(1:144,1:144,vsize(3)-575:vsize(3)-288);
            app.volume = v;
            viewer = app.OrthoSliceViewerPanel;
            orthosliceViewer(app.volume,'Parent',viewer);

            intensity = [0 20 40 120 220 1024];
            alpha = [0 0 0.15 0.3 0.38 0.5];
            color = [0 0 0; 43 0 0; 103 37 20; 199 155 97; 216 213 201; 255 255 255]/255;
            queryPoints = linspace(min(intensity),max(intensity),256);
            alphamap = interp1(intensity,alpha,queryPoints)';
            colormap = interp1(intensity,color,queryPoints);
            app.hVol = volshow(v,'Parent',app.volumeViewerPanel,Colormap=colormap, Alphamap=alphamap);
            app.hVol.Colormap=gray;
            app.hVol.Data = app.volume;

            app.x = X;
            app.y = Y;
            app.z = Z;

            app.SelectedDataField.Value = app.file;
        end

        % Button pushed function: DecideDataBtn
        function DecideDataBtnPushed(app, event)
            X = app.x; Y = app.y; Z = app.z;
            ta = app.thA; tb = app.thB; tc = app.thC;
            td = app.thD; te = app.thE; tf = app.thF;
            tg = app.thG; th = app.thH; ti = app.thI;
            v = app.volume;    
            [app.T,app.B,app.H]=makeTBH(app,v,X,Y,Z,ta,tb,tc,td,te,tf,tg,th,ti);
            im=(double(app.T)+double(app.H)+double(app.B)*2)/4;
            viewer = app.OrthoSliceViewerPanel;
            orthosliceViewer(im,'Parent',viewer,'Colormap',gray);
    
            app.hVol.Data = im;
        end

        % Button pushed function: makeTrainDataBtn
        function makeTrainDataBtnPushed(app, event)
            SelectLeg = app.WhichLegGroup.SelectedObject;
            % 'VolumeData'ディレクトリの存在を確認し、存在しない場合のみ作成する
            if ~exist('VolumeData', 'dir')
                mkdir('VolumeData');
            end
            
            % 'BoneData'ディレクトリの存在を確認し、存在しない場合のみ作成する
            if ~exist('BoneData', 'dir')
                mkdir('BoneData');
            end

            im = makeTrainData(app,app.file, SelectLeg.Text,app.T,app.B,app.H);

            %右足はそのまま保存
%             if SelectLeg.Text=="右足"
%                 Vfn=insertBefore(app.file,".mat","_R_vol");
%                 Vfn=strjoin([string(pwd) 'VolumeData' Vfn],'/');
%                 vol=uint8(app.T);
%                 % 1mm->2mm
%     %             vol=vol(1:2:143,1:2:143,1:2:287);
%                 save(Vfn,'vol');
%                 Bfn=insertBefore(app.file,".mat","_R_bone");
%                 Bfn=strjoin([string(pwd) 'BoneData' Bfn],'/');
%                 bone=uint8(double(app.T)+double(app.H)*2+double(app.B));
%                 bone4=bone==4;
%                 bone(bone4)=3;
%     %             bone=bone(1:2:143,1:2:143,1:2:287);
%                 save(Bfn,'bone');
%                 im=cat(1,vol,bone);
%     %             setVolume(hVol,im);
%                 app.hVol.Data = im;
%     
%             %左足は左右反転して保存
%             else
%                 Vfn=insertBefore(app.file,".mat","_L(rev)_vol");
%                 Vfn=strjoin([string(pwd) 'VolumeData' Vfn],'/');
%                 vol=uint8(app.T);
%     %             vol=vol(1:2:143,1:2:143,1:2:287);
%     
%                 vol=flip(vol,2);
%     
%                 save(Vfn,'vol');
%                 Bfn=insertBefore(app.file,".mat","_L(rev)_bone");
%                 Bfn=strjoin([string(pwd) 'BoneData' Bfn],'/');
%                 bone=uint8(double(app.T)+double(app.H)*2+double(app.B));
%                 bone4=bone==4;
%                 bone(bone4)=3;
%     %             bone=bone(1:2:143,1:2:143,1:2:287);
%     
%                 bone=flip(bone,2);
%     
%                 save(Bfn,'bone');
%                 im=cat(1,vol,bone);
    %             setVolume(hVol,im);
%         end
                app.hVol.Data = im;
           
        end

        % Value changed function: threshold1Field
        function threshold1FieldValueChanged(app, event)
            value = app.threshold1Field.Value;
            app.thA = value;
        end

        % Value changed function: threshold2Field
        function threshold2FieldValueChanged(app, event)
            value = app.threshold2Field.Value;
            app.thB = value;
        end

        % Value changed function: threshold3Field
        function threshold3FieldValueChanged(app, event)
            value = app.threshold3Field.Value;
            app.thC = value;
        end

        % Value changed function: mm3EditField_6
        function mm3EditField_6ValueChanged(app, event)
            value = app.mm3EditField_6.Value;
            app.thD = value;
        end

        % Value changed function: mm3CTEditField_3
        function mm3CTEditField_3ValueChanged(app, event)
            value = app.mm3CTEditField_3.Value;
            app.thE = value;
        end

        % Value changed function: mm3EditField_7
        function mm3EditField_7ValueChanged(app, event)
            value = app.mm3EditField_7.Value;
            app.thF = value;
        end

        % Value changed function: mm3CTEditField_4
        function mm3CTEditField_4ValueChanged(app, event)
            value = app.mm3CTEditField_4.Value;
            app.thG = value;
        end

        % Value changed function: mm3EditField_8
        function mm3EditField_8ValueChanged(app, event)
            value = app.mm3EditField_8.Value;
            app.thH = value;
        end

        % Value changed function: mm2CTEditField_2
        function mm2CTEditField_2ValueChanged(app, event)
            value = app.mm2CTEditField_2.Value;
            app.thI = value;
        end

        % Button pushed function: ResetDataBtn
        function ResetDataBtnButtonPushed(app, event)
            app.x=1;
            app.y=1;
            app.z=1;
            app.volume=zeros([2 2 2]);
            
            app.T=0;
            app.B=0;
            app.H=0;
            app.file='0';
            app.path='0';
            app.hVol= '';

            app.SelectedDataField.Value= '';
            app.ExportDataField.Value = '';
        end

        % Button pushed function: DirSelectDataBtn
        function DirSelectDataBtnButtonPushed(app, event)
            % Select the directory
            dirPath = uigetdir; 
            if dirPath == 0
                return; % return early if user cancels directory selection
            end
                
            % Get the .mat files
            app.matFiles = dir(fullfile(dirPath, '*.mat'));
            disp(app.matFiles)
            
            app.ListBox.Items = {app.matFiles.name};
            % ファイルのパスをappに保存
            app.matFilePaths = fullfile(dirPath, {app.matFiles.name});
            app.DataLengthField.Value = length(app.matFiles);
            disp('nagasa')
            disp(length(app.matFilePaths))

            % 選択されたファイルを読み込む
            vData = load(app.matFilePaths{1},'v','dicominfo');
%             disp(vData);
            % データを表示
%             volshow(vData.v,'Parent',app.volumeViewerPanel);
            intensity = [0 20 40 120 220 1024];
            alpha = [0 0 0.15 0.3 0.38 0.5];
            color = [0 0 0; 43 0 0; 103 37 20; 199 155 97; 216 213 201; 255 255 255]/255;
            queryPoints = linspace(min(intensity),max(intensity),256);
            alphamap = interp1(intensity,alpha,queryPoints)';
            colormap = interp1(intensity,color,queryPoints);
            app.hVol = volshow(vData.v,'Parent',app.volumeViewerPanel,Colormap=colormap, Alphamap=alphamap);
            app.hVol.Colormap=gray;
            app.hVol.Data = vData.v;

            viewer = app.OrthoSliceViewerPanel;
            orthosliceViewer(vData.v,'Parent',viewer);
            app.SelectedDirField.Value = string(dirPath);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % 選択されたファイルのインデックスを取得
            selectedIndex = app.ListBox.Value;
        
            % 選択されたファイルを読み込む
            vData = load(app.matFilePaths{selectedIndex},'v','dicominfo');
            
            % データを表示
            app.hVol.Data = vData.v;
%             volshow(vData.v,'Parent',app.volumeViewerPanel);
            viewer = app.OrthoSliceViewerPanel;
            orthosliceViewer(vData.v,'Parent',viewer);
        end

        % Button pushed function: sampleViewBtn
        function sampleViewBtnButtonPushed(app, event)
            X = app.x; Y = app.y; Z = app.z;
            ta = app.thA; tb = app.thB; tc = app.thC;
            td = app.thD; te = app.thE; tf = app.thF;
            tg = app.thG; th = app.thH; ti = app.thI;
            v = app.volume;    
            [T_s,B_s,H_s]=makeTBH(app,v,X,Y,Z,ta,tb,tc,td,te,tf,tg,th,ti);
            im=(double(T_s)+double(H_s)+double(B_s)*2)/4;
            viewer = app.OrthoSliceViewerPanel;
            orthosliceViewer(im,'Parent',viewer,'Colormap',gray);
    
            app.hVol.Data = im;
        end

        % Button pushed function: makeTrainDataALLBtn
        function makeTrainDataALLBtnButtonPushed(app, event)
            ta = app.thA; tb = app.thB; tc = app.thC;
            td = app.thD; te = app.thE; tf = app.thF;
            tg = app.thG; th = app.thH; ti = app.thI;
            data = app.matFilePaths;
            SelectLeg = app.WhichLegGroup_2.Text;
            % ユーザーに確認を求めるダイアログを表示
            choice = questdlg('処理を開始してもよろしいですか？', ...
                              '確認', ...
                              'OK', 'キャンセル', 'キャンセル');
            % ユーザーがOKを選択した場合、処理を実行

            switch choice
                case 'OK'
                    % 'VolumeData'ディレクトリの存在を確認し、存在しない場合のみ作成する
                    if ~exist('VolumeData', 'dir')
                        mkdir('VolumeData');
                    end                    
                    % 'BoneData'ディレクトリの存在を確認し、存在しない場合のみ作成する
                    if ~exist('BoneData', 'dir')
                        mkdir('BoneData');
                    end

                    for idx = 1:length(data)
                        load(data{1},'v','dicominfo');
                        X=dicominfo.PixelSpacings(1,1);
                        Y=dicominfo.PixelSpacings(2,1);
                        Z=dicominfo.PatientPositions(2,3)-dicominfo.PatientPositions(1,3);
                                
                        %1ピクセル=1mmになるように拡大縮小
                        vsize=[fix(size(v,1)*X) fix(size(v,2)*Y) fix(size(v,3)*Z)];
                        v=imresize3(v,vsize);
                        X=1;Y=1;Z=1;
                        
                        %144*144*288mmに調整
                        v=padarray(v,[144 144 288],-2048,'post');
                        vsize=size(v);
                        v=v(1:144,1:144,vsize(3)-575:vsize(3)-288);
                        [t, b, h] = makeTBH(app,v,X,Y,Z,ta,tb,tc,td,te,tf,tg,th,ti);
                        im = makeTrainData(app,app.file, SelectLeg.Text,t,b,h);
                    end
                otherwise
                    disp('キャンセルされました');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrthoSliceViewerPanel = uipanel(app.UIFigure);
            app.volumeViewerPanel = uipanel(app.OrthoSliceViewerPanel);
            app.Panel_2 = uipanel(app.UIFigure);
            app.threshold1Field = uieditfield(app.Panel_2, 'numeric');
            app.threshold1Field.ValueChangedFcn = createCallbackFcn(app, @threshold1FieldValueChanged, true);
            app.threshold1Field.Value = -100;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '空気-軟部組織閾値';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '軟部組織-角質閾値';
            app.threshold2Field = uieditfield(app.Panel_2, 'numeric');
            app.threshold2Field.ValueChangedFcn = createCallbackFcn(app, @threshold2FieldValueChanged, true);
            app.threshold2Field.Value = 140;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '角質-骨閾値';
            app.threshold3Field = uieditfield(app.Panel_2, 'numeric');
            app.threshold3Field.ValueChangedFcn = createCallbackFcn(app, @threshold3FieldValueChanged, true);
            app.threshold3Field.Value = 300;
            app.mm3EditField_6Label = uilabel(app.Panel_2);
            app.mm3EditField_6Label.Text = 'mm^3以下の分離した軟部組織を削除';
            app.mm3EditField_6 = uieditfield(app.Panel_2, 'numeric');
            app.mm3EditField_6.ValueChangedFcn = createCallbackFcn(app, @mm3EditField_6ValueChanged, true);
            app.mm3EditField_6.Value = 500000;
            app.mm3CTEditField_3Label = uilabel(app.Panel_2);
            app.mm3CTEditField_3Label.Text = 'mm^3以下の軟部組織内の低CT値領域を削除';
            app.mm3CTEditField_3 = uieditfield(app.Panel_2, 'numeric');
            app.mm3CTEditField_3.ValueChangedFcn = createCallbackFcn(app, @mm3CTEditField_3ValueChanged, true);
            app.mm3CTEditField_3.Value = 500000;
            app.mm3EditField_7Label = uilabel(app.Panel_2);
            app.mm3EditField_7Label.Text = 'mm^3以下の分離した角質を削除';
            app.mm3EditField_7 = uieditfield(app.Panel_2, 'numeric');
            app.mm3EditField_7.ValueChangedFcn = createCallbackFcn(app, @mm3EditField_7ValueChanged, true);
            app.mm3EditField_7.Value = 40000;
            app.mm3CTEditField_4Label = uilabel(app.Panel_2);
            app.mm3CTEditField_4Label.Text = 'mm^3以下の角質内の低CT値領域を削除';
            app.mm3CTEditField_4 = uieditfield(app.Panel_2, 'numeric');
            app.mm3CTEditField_4.ValueChangedFcn = createCallbackFcn(app, @mm3CTEditField_4ValueChanged, true);
            app.mm3CTEditField_4.Value = 500000;
            app.mm3EditField_8Label = uilabel(app.Panel_2);
            app.mm3EditField_8Label.Text = 'mm^3以下の分離した骨を削除';
            app.mm3EditField_8 = uieditfield(app.Panel_2, 'numeric');
            app.mm3EditField_8.ValueChangedFcn = createCallbackFcn(app, @mm3EditField_8ValueChanged, true);
            app.mm3EditField_8.Value = 100000;
            app.mm2CTLabel = uilabel(app.Panel_2);
            app.mm2CTLabel.Text = 'mm^2以下の骨内の低CT値領域を削除';
            app.mm2CTEditField_2 = uieditfield(app.Panel_2, 'numeric');
            app.mm2CTEditField_2.ValueChangedFcn = createCallbackFcn(app, @mm2CTEditField_2ValueChanged, true);
            app.mm2CTEditField_2.Value = 10000;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OnlyOneTab = uitab(app.TabGroup);
            app.OnlyOneTab.Title = 'OnlyOne';
            app.SelectDataBtn = uibutton(app.OnlyOneTab, 'push');
            app.SelectDataBtn.ButtonPushedFcn = createCallbackFcn(app, @SelectDataBtnButtonPushed, true);
            app.SelectDataBtn.Text = '選択';
            app.WhichLegGroup = uibuttongroup(app.OnlyOneTab);
            app.RightLeg = uiradiobutton(app.WhichLegGroup);
            app.RightLeg.Text = '右足';
            app.RightLeg.Value = true;
            app.LeftLeg = uiradiobutton(app.WhichLegGroup);
            app.LeftLeg.Text = '左足';
            app.DecideDataBtn = uibutton(app.OnlyOneTab, 'push');
            app.DecideDataBtn.ButtonPushedFcn = createCallbackFcn(app, @DecideDataBtnPushed, true);
            app.DecideDataBtn.Text = '決定';
            app.makeTrainDataBtn = uibutton(app.OnlyOneTab, 'push');
            app.makeTrainDataBtn.ButtonPushedFcn = createCallbackFcn(app, @makeTrainDataBtnPushed, true);
            app.makeTrainDataBtn.Text = '学習データエクスポート';
            app.SelectedDataField = uieditfield(app.OnlyOneTab, 'text');
            app.ExportDataField = uieditfield(app.OnlyOneTab, 'text');
            app.ResetDataBtn = uibutton(app.OnlyOneTab, 'push');
            app.ResetDataBtn.ButtonPushedFcn = createCallbackFcn(app, @ResetDataBtnButtonPushed, true);
            app.ResetDataBtn.Text = 'Reset';
            app.ForDirTab = uitab(app.TabGroup);
            app.ForDirTab.Title = 'ForDir';
            app.DirSelectDataBtn = uibutton(app.ForDirTab, 'push');
            app.DirSelectDataBtn.ButtonPushedFcn = createCallbackFcn(app, @DirSelectDataBtnButtonPushed, true);
            app.DirSelectDataBtn.Text = 'ディレクトリ選択';
            app.SelectedDirField = uieditfield(app.ForDirTab, 'text');
            app.WhichLegGroup_2 = uibuttongroup(app.ForDirTab);
            app.RightLeg_2 = uiradiobutton(app.WhichLegGroup_2);
            app.RightLeg_2.Text = '右足';
            app.RightLeg_2.Value = true;
            app.LeftLeg_2 = uiradiobutton(app.WhichLegGroup_2);
            app.LeftLeg_2.Text = '左足';
            app.Label_7 = uilabel(app.ForDirTab);
            app.Label_7.Text = 'データ数';
            app.DataLengthField = uieditfield(app.ForDirTab, 'numeric');
            app.ResetDataBtn_2 = uibutton(app.ForDirTab, 'push');
            app.ResetDataBtn_2.Text = 'Reset';
            app.makeTrainDataALLBtn = uibutton(app.ForDirTab, 'push');
            app.makeTrainDataALLBtn.ButtonPushedFcn = createCallbackFcn(app, @makeTrainDataALLBtnButtonPushed, true);
            app.makeTrainDataALLBtn.Text = '学習データエクスポート';
            app.ListBox = uilistbox(app.ForDirTab);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.sampleViewBtn = uibutton(app.ForDirTab, 'push');
            app.sampleViewBtn.ButtonPushedFcn = createCallbackFcn(app, @sampleViewBtnButtonPushed, true);
            app.sampleViewBtn.Text = 'サンプルビュー';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = makeTrainingData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end